classdef Bordes4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LaplacianoButton         matlab.ui.control.Button
        PrewittButton            matlab.ui.control.Button
        HistogramaButton         matlab.ui.control.Button
        VolverButton             matlab.ui.control.Button
        Label_3                  matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        AyudaButton              matlab.ui.control.Button
        RestablecerimagenButton  matlab.ui.control.Button
        ModificarbordesLabel     matlab.ui.control.Label
        Sobel_BordeButton        matlab.ui.control.Button
        CambiarimagenButton      matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Imagen)
              app.UIAxes.reset
              axis(app.UIAxes, 'tight')
              imshow(Imagen, 'Parent', app.UIAxes);
            
        end

        % Button pushed function: CambiarimagenButton
        function CambiarimagenButtonPushed(app, event)
            % Cargar archivo
                global Imagen 
                startingFolder = 'C:\Users\Hipolito\Downloads\';
                if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
                end
                defaultFileName = fullfile(startingFolder, '*.jpg*');
                [baseFileName, folder] = uigetfile(defaultFileName, 'Seleccionar un archivo');
                if baseFileName == 0
                return;
                end
                fullFileName = fullfile(folder, baseFileName);
                Imagen = imread(fullFileName);
                % Mostrar imagen
                app.UIAxes.reset
                axis(app.UIAxes, 'tight')
                imshow(Imagen, 'Parent', app.UIAxes)
        end

        % Button pushed function: Sobel_BordeButton
        function Sobel_BordeButtonPushed(app, event)
            global Imagen
            global Imagen2
       Imagen = imresize(Imagen, 2);
       img_gris= rgb2gray(Imagen);

%% filtro de sobel (detección de bordes)
gx =        [-1 -2 -1;   %filtro sobel en x 
             0 0 0;
             1 2 1]; 
gy =        [-1 0 1;   %filtro sobel en y
             -2 0 2;
             -1 0 1]; 
                 
img_filt_x = imfilter(img_gris, gx); % filtrado por Convolución
img_filt_y = imfilter(img_gris, gy); % filtrado por Convolución

img_filt_x = double(img_filt_x); %Conversion a double
img_filt_y = double(img_filt_y);

mag_grad_1 = sqrt(img_filt_x.^2 +img_filt_y.^2);  %Magnitud del gradiente
Imagen2 = abs(img_filt_x) + abs(img_filt_y);  %Magnitud del gradiente

mag_grad_1 = uint8(mag_grad_1); %conversion a uint8
Imagen2 = uint8(Imagen2);

        app.UIAxes.reset
        axis(app.UIAxes, 'tight')
        imshow(Imagen2, 'Parent', app.UIAxes)        
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
f = msgbox({'1. Encuentra bordes en aquellos puntos donde el degradado de la imagen es máximo.';'2. Busca aristas en aquellos puntos donde el degradado es máximo.';'3. Disminuye considerablemente el número de grises presentes en la imagen original.'; ...
                '4. Utiliza la herramienta para reestablecer tu imagen si no te agrada el resultado.';'5. Observa el histograma y compara los resultados.';'6. Puedes guardar tu imagen mejorada con ayuda de las opciones que se muestran arriba de la misma.'}, ...
                'Guia de IMPROVE APP');
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            global Imagen
            global Imagen2
            Imagen=Imagen2; 
            
            close(app.UIFigure);
            MAIN2(Imagen)      
                        
        end

        % Button pushed function: RestablecerimagenButton
        function RestablecerimagenButtonPushed(app, event)
            global Imagen
            global Imagen2
            
            app.UIAxes.reset
            axis(app.UIAxes, 'tight')
            imshow(Imagen, 'Parent', app.UIAxes)
            Imagen2=Imagen;
        end

        % Button pushed function: HistogramaButton
        function HistogramaButtonPushed(app, event)
            global Imagen
            global Imagen2
            
            Imagen3=Imagen;
            Imagen3=Imagen2;
            
%             axis(app.UIAxes, 'tight')
            
%            Imagen3=rgb2gray(Imagen3)
            figure("Name",'Histograma',"Color",'b')            
            imhist(Imagen3)                    
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            global Imagen
            global Imagen2
            Imagen = imresize(Imagen, 2);
            img_gris= rgb2gray(Imagen);

%% filtro de sobel (detección de bordes)
gx =        [-1 -1 -1;   %filtro prewitt en x 
             0 0 0;
             1 1 1]; 
gy =        [-1 0 1;   %filtro prewwit en y
             -1 0 1;
             -1 0 1]; 
                 
img_filt_x = imfilter(img_gris, gx); % filtrado por Convolución
img_filt_y = imfilter(img_gris, gy); % filtrado por Convolución

img_filt_x = double(img_filt_x); %Conversion a double
img_filt_y = double(img_filt_y);

Imagen2 = sqrt(img_filt_x.^2 +img_filt_y.^2);  %Magnitud del gradiente
mag_grad_2 = abs(img_filt_x) + abs(img_filt_y);  %Magnitud del gradiente

Imagen2 = uint8(Imagen2); %conversion a uint8
mag_grad_2 = uint8(mag_grad_2);

        app.UIAxes.reset
        axis(app.UIAxes, 'tight')
        imshow(Imagen2, 'Parent', app.UIAxes) 
        end

        % Button pushed function: LaplacianoButton
        function LaplacianoButtonPushed(app, event)
            global Imagen
            global Imagen2
            Imagen = imresize(Imagen, 2);
            %img_gris= rgb2gray(img);

%% filtro de gradiente (detección de bordes)
laplaciano_1 =       [0 1 0;   %filtro laplaciano
                     1 -4 1;
                     0 1 0];                  

laplaciano_2 =       [0 -1 0;   %filtro laplaciano
                     -1 4 -1;
                      0 -1 0];     
                  
laplaciano_3 =       [1 1 1;   %filtro laplaciano
                      1 -8 1;
                      1 1 1];   

laplaciano_4 =       [-1 -1 -1;   %filtro laplaciano
                      -1 8 -1;
                      -1 -1 -1];     
Imagen2 = imfilter(Imagen, laplaciano_4); % filtrado por Convolución

        app.UIAxes.reset
        axis(app.UIAxes, 'tight')
        imshow(Imagen2, 'Parent', app.UIAxes) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.CambiarimagenButton = uibutton(app.UIFigure, 'push');
            app.CambiarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CambiarimagenButtonPushed, true);
            app.CambiarimagenButton.Text = 'Cambiar imagen';
            app.Sobel_BordeButton = uibutton(app.UIFigure, 'push');
            app.Sobel_BordeButton.ButtonPushedFcn = createCallbackFcn(app, @Sobel_BordeButtonPushed, true);
            app.Sobel_BordeButton.Text = 'Sobel_Borde';
            app.ModificarbordesLabel = uilabel(app.UIFigure);
            app.ModificarbordesLabel.Text = 'Modificar bordes';
            app.RestablecerimagenButton = uibutton(app.UIFigure, 'push');
            app.RestablecerimagenButton.ButtonPushedFcn = createCallbackFcn(app, @RestablecerimagenButtonPushed, true);
            app.RestablecerimagenButton.Text = 'Restablecer imagen';
            app.AyudaButton = uibutton(app.UIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda (?)';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'UNITEC IMG.jpg';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = '<-Volver';
            app.HistogramaButton = uibutton(app.UIFigure, 'push');
            app.HistogramaButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramaButtonPushed, true);
            app.HistogramaButton.Text = 'Histograma';
            app.PrewittButton = uibutton(app.UIFigure, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.LaplacianoButton = uibutton(app.UIFigure, 'push');
            app.LaplacianoButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianoButtonPushed, true);
            app.LaplacianoButton.Text = 'Laplaciano';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bordes4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end