classdef Contraste6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RestablecerimagenButton       matlab.ui.control.Button
        HistogramaButton              matlab.ui.control.Button
        Factor                        matlab.ui.control.Spinner
        FactorLabel                   matlab.ui.control.Label
        Gamma                         matlab.ui.control.Spinner
        GammaLabel                    matlab.ui.control.Label
        VolverButton                  matlab.ui.control.Button
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        AyudaButton                   matlab.ui.control.Button
        StretchingfactorButton        matlab.ui.control.Button
        ArreglarcontrastebrilloLabel  matlab.ui.control.Label
        AjustegammaButton             matlab.ui.control.Button
        CambiarimagenButton           matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Imagen)
             app.UIAxes.reset
             axis(app.UIAxes, 'tight')
             imshow(Imagen, 'Parent', app.UIAxes)
        end

        % Button pushed function: CambiarimagenButton
        function CambiarimagenButtonPushed(app, event)
            % Cargar archivo
                global Imagen 
                startingFolder = 'C:\Users\Hipolito\Downloads\';
                if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
                end
                defaultFileName = fullfile(startingFolder, '*.jpg*');
                [baseFileName, folder] = uigetfile(defaultFileName, 'Seleccionar un archivo');
                if baseFileName == 0
                return;
                end
                fullFileName = fullfile(folder, baseFileName);
                Imagen = imread(fullFileName);
                % Mostrar imagen
                app.UIAxes.reset
                axis(app.UIAxes, 'tight')
                imshow(Imagen, 'Parent', app.UIAxes)
        end

        % Button pushed function: AjustegammaButton
        function AjustegammaButtonPushed(app, event)
            global Imagen
            global Imagen2
            
            %% AJUSTE GAMMA
Imagen3=Imagen;
%img = rgb2gray(img); %Escala de grises
Imagen3 = double(Imagen3); %Imagen en formato double

gamma = app.Gamma.Value;

Imagen2 = 255.*((Imagen3./255).^(1/gamma));%Ajuste gamma
%
% Imagen3 = uint8(Imagen3); %Conversion a uint8 AQUI
Imagen2 = uint8(Imagen2);

app.UIAxes.reset
axis(app.UIAxes, 'tight')
imshow(Imagen2, 'Parent', app.UIAxes)    
        end

        % Button pushed function: StretchingfactorButton
        function StretchingfactorButtonPushed(app, event)
                        %%STRETCHING FACTOR
            global Imagen
            global Imagen2                      
       %Imagen5 = rgb2gray(Imagen);
       m = min(Imagen(:)); %valor minimo de pixel en la imagen
      %m = 5;
       factor = app.Factor.Value;
       Imagen2 = (Imagen - m).*factor; %Stretching
                
       app.UIAxes.reset
       axis(app.UIAxes, 'tight')
       imshow(Imagen2, 'Parent', app.UIAxes)              
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            f = msgbox({'1. Puedes indicar el valor que tu quieras para gamma y factor, y en seguida darle al boton para que haga el mejoramiento.';'2. Utiliza la herramienta para reestablecer tu imagen si no te agrada el resultado. '; ...
                '3. Observa el histograma y compara los resultados.';'4. Puedes guardar tu imagen mejorada con ayuda de las opciones que se muestran arriba de la misma.'}, 'Guia de IMPROVE APP');
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            global Imagen
            global Imagen2
            Imagen=Imagen2; 
            
            close(app.UIFigure);
            MAIN2(Imagen)        
                        
        end

        % Button pushed function: HistogramaButton
        function HistogramaButtonPushed(app, event)
            global Imagen
            global Imagen2
            
            Imagen3=Imagen;
            Imagen3=Imagen2;
            
%             axis(app.UIAxes, 'tight')
            
            %Imagen3=rgb2gray(Imagen3)
            figure("Name",'Histograma',"Color",'b')            
            imhist(Imagen3)
        end

        % Button pushed function: RestablecerimagenButton
        function RestablecerimagenButtonPushed(app, event)
            global Imagen
            global Imagen2
            
            app.UIAxes.reset
            axis(app.UIAxes, 'tight')
            imshow(Imagen, 'Parent', app.UIAxes)
            Imagen2=Imagen;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.CambiarimagenButton = uibutton(app.UIFigure, 'push');
            app.CambiarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CambiarimagenButtonPushed, true);
            app.CambiarimagenButton.Text = 'Cambiar imagen';
            app.AjustegammaButton = uibutton(app.UIFigure, 'push');
            app.AjustegammaButton.ButtonPushedFcn = createCallbackFcn(app, @AjustegammaButtonPushed, true);
            app.AjustegammaButton.Text = 'Ajuste gamma';
            app.ArreglarcontrastebrilloLabel = uilabel(app.UIFigure);
            app.ArreglarcontrastebrilloLabel.Text = 'Arreglar contraste/brillo';
            app.StretchingfactorButton = uibutton(app.UIFigure, 'push');
            app.StretchingfactorButton.ButtonPushedFcn = createCallbackFcn(app, @StretchingfactorButtonPushed, true);
            app.StretchingfactorButton.Text = 'Stretching factor';
            app.AyudaButton = uibutton(app.UIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda (?)';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'UNITEC IMG.jpg';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = '<-Volver';
            app.GammaLabel = uilabel(app.UIFigure);
            app.GammaLabel.Text = 'Gamma:';
            app.Gamma = uispinner(app.UIFigure);
            app.FactorLabel = uilabel(app.UIFigure);
            app.FactorLabel.Text = 'Factor:';
            app.Factor = uispinner(app.UIFigure);
            app.HistogramaButton = uibutton(app.UIFigure, 'push');
            app.HistogramaButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramaButtonPushed, true);
            app.HistogramaButton.Text = 'Histograma';
            app.RestablecerimagenButton = uibutton(app.UIFigure, 'push');
            app.RestablecerimagenButton.ButtonPushedFcn = createCallbackFcn(app, @RestablecerimagenButtonPushed, true);
            app.RestablecerimagenButton.Text = 'Restablecer imagen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Contraste6(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end