classdef MAIN2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        ClasificarimagenconredneuronalButton  matlab.ui.control.Button
        AyudaButton                    matlab.ui.control.Button
        ModificarbordesButton          matlab.ui.control.Button
        QuitarruidoButton              matlab.ui.control.Button
        ArreglarcontrastebrilloButton  matlab.ui.control.Button
        BienvenidoaIMPROVEAPPLabel     matlab.ui.control.Label
        MejorarpidaButton              matlab.ui.control.Button
        SeleccionarimagenButton        matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Imagen)
            app.UIAxes.reset
            axis(app.UIAxes, 'tight')
            imshow(Imagen, 'Parent', app.UIAxes)
        end

        % Button pushed function: SeleccionarimagenButton
        function SeleccionarimagenButtonPushed(app, event)
            % Cargar archivo
                global Imagen 
                startingFolder = 'C:\Users\Hipolito\Downloads\';
                if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
                end
                defaultFileName = fullfile(startingFolder, '*.jpg*');
                [baseFileName, folder] = uigetfile(defaultFileName, 'Seleccionar un archivo');
                if baseFileName == 0
                return;
                end
                fullFileName = fullfile(folder, baseFileName);
                Imagen = imread(fullFileName);
                % Mostrar imagen
                %app.UIAxes.reset
                axis(app.UIAxes, 'tight')
                imshow(Imagen, 'Parent', app.UIAxes)
        end

        % Button pushed function: MejorarpidaButton
        function MejorarpidaButtonPushed(app, event)
        global Imagen
        SIMPLE3(Imagen)
        close(app.UIFigure);        
        end

        % Button pushed function: ArreglarcontrastebrilloButton
        function ArreglarcontrastebrilloButtonPushed(app, event)
        global Imagen
        Contraste6(Imagen)
        close(app.UIFigure);             
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            f = msgbox({'1. Mejora tu imagen';'2. Utiliza diversas tecnicas para mejorar el brillo o contraste de la imagen';'3. Ideal para imágenes dañadas o con puntos que obstruyen la imagen';'4. Realza bordes o remueve para hacer más visibles los objetos'; ...
                '5. Puedes clasificar tus imagenes con ayuda de una red neuronal.'},'Guia de IMPROVE APP');
        end

        % Button pushed function: QuitarruidoButton
        function QuitarruidoButtonPushed(app, event)
            global Imagen
            ruido4(Imagen)
            close(app.UIFigure);
        end

        % Button pushed function: ModificarbordesButton
        function ModificarbordesButtonPushed(app, event)
            global Imagen
            Bordes4(Imagen)
            close(app.UIFigure);            
        end

        % Button pushed function: 
        % ClasificarimagenconredneuronalButton
        function ClasificarimagenconredneuronalButtonPushed(app, event)
           global Imagen
           NEURAL2(Imagen)
           close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.SeleccionarimagenButton = uibutton(app.UIFigure, 'push');
            app.SeleccionarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarimagenButtonPushed, true);
            app.SeleccionarimagenButton.Text = 'Seleccionar imagen';
            app.MejorarpidaButton = uibutton(app.UIFigure, 'push');
            app.MejorarpidaButton.ButtonPushedFcn = createCallbackFcn(app, @MejorarpidaButtonPushed, true);
            app.MejorarpidaButton.Text = 'Mejora rápida';
            app.BienvenidoaIMPROVEAPPLabel = uilabel(app.UIFigure);
            app.BienvenidoaIMPROVEAPPLabel.Text = 'Bienvenido a IMPROVE APP';
            app.ArreglarcontrastebrilloButton = uibutton(app.UIFigure, 'push');
            app.ArreglarcontrastebrilloButton.ButtonPushedFcn = createCallbackFcn(app, @ArreglarcontrastebrilloButtonPushed, true);
            app.ArreglarcontrastebrilloButton.Text = 'Arreglar contraste/brillo';
            app.QuitarruidoButton = uibutton(app.UIFigure, 'push');
            app.QuitarruidoButton.ButtonPushedFcn = createCallbackFcn(app, @QuitarruidoButtonPushed, true);
            app.QuitarruidoButton.Text = 'Quitar ruido';
            app.ModificarbordesButton = uibutton(app.UIFigure, 'push');
            app.ModificarbordesButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarbordesButtonPushed, true);
            app.ModificarbordesButton.Text = 'Modificar bordes';
            app.AyudaButton = uibutton(app.UIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda (?)';
            app.ClasificarimagenconredneuronalButton = uibutton(app.UIFigure, 'push');
            app.ClasificarimagenconredneuronalButton.ButtonPushedFcn = createCallbackFcn(app, @ClasificarimagenconredneuronalButtonPushed, true);
            app.ClasificarimagenconredneuronalButton.Text = 'Clasificar imagen con red neuronal';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'UNITEC IMG.jpg';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAIN2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end