classdef NEURAL2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        VolverButton         matlab.ui.control.Button
        Label_3              matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        Image                matlab.ui.control.Image
        AyudaButton          matlab.ui.control.Button
        ClasificarButton     matlab.ui.control.Button
        GOOGLENETLabel       matlab.ui.control.Label
        Label                matlab.ui.control.Label
        CambiarimagenButton  matlab.ui.control.Button
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Imagen)
            
            axis(app.UIAxes, 'tight')
            imshow(Imagen, 'Parent', app.UIAxes)
        end

        % Button pushed function: CambiarimagenButton
        function CambiarimagenButtonPushed(app, event)
            % Cargar archivo
                global Imagen 
                startingFolder = 'C:\Users\Hipolito\Downloads\';
                if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
                end
                defaultFileName = fullfile(startingFolder, '*.jpg*');
                [baseFileName, folder] = uigetfile(defaultFileName, 'Seleccionar un archivo');
                if baseFileName == 0
                return;
                end
                fullFileName = fullfile(folder, baseFileName);
                Imagen = imread(fullFileName);
                % Mostrar imagen
                app.UIAxes.reset
                axis(app.UIAxes, 'tight')
                imshow(Imagen, 'Parent', app.UIAxes)
        end

        % Button pushed function: ClasificarButton
        function ClasificarButtonPushed(app, event)
            global Imagen
            global Imagen2
             neuralnet
             load('neuralnet.mat');
             inputSize = net.Layers(1).InputSize;
             classNames = net.Layers(end).ClassNames;
             numClasses = numel(classNames);


            Imagen2= imresize(Imagen,inputSize(1:2));
                     
            [label,scores] = classify(net, Imagen2);


%             figure
%             axis(app.UIAxes2, 'tight')
%             imshow(Imagen, 'Parent', app.UIAxes2)

            title(app.UIAxes2,string(label)+", "+num2str(100*scores(classNames==label),3)+"%",'Color','white',"FontSize",20)

[~,idx] = sort(scores,'descend');
idx= idx(5:-1:1);
classNamesTop = net.Layers(end).ClassNames(idx);
scoresTop = scores(idx);
% 
% figure

barh(app.UIAxes2,scoresTop)
xlim(app.UIAxes2,[0 1])
title(app.UIAxes2,'Top 5 posibles coincidencias','Color','white',"FontSize",20);
%ylabel(app.UIAxes2,'Posibles coincidencias',"FontSize",20,'Color','white')
yticklabels(app.UIAxes2,classNamesTop)


        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            f = msgbox({'1. Clasifica tu imagen';'2. Mostrara 5 coincidencias que tenga tu imagen';}, ...
            'Guia de IMPROVE APP');
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            global Imagen
            global Imagen2
            Imagen=Imagen2; 
            
            close(app.UIFigure);
            MAIN2(Imagen)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.CambiarimagenButton = uibutton(app.UIFigure, 'push');
            app.CambiarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CambiarimagenButtonPushed, true);
            app.CambiarimagenButton.Text = 'Cambiar imagen';
            app.Label = uilabel(app.UIFigure);
            app.GOOGLENETLabel = uilabel(app.UIFigure);
            app.GOOGLENETLabel.Text = 'GOOGLENET';
            app.ClasificarButton = uibutton(app.UIFigure, 'push');
            app.ClasificarButton.ButtonPushedFcn = createCallbackFcn(app, @ClasificarButtonPushed, true);
            app.ClasificarButton.Text = 'Clasificar';
            app.AyudaButton = uibutton(app.UIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda (?)';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'UNITEC IMG.jpg';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = '<-Volver';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NEURAL2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end