classdef ruido4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Spinner                        matlab.ui.control.Spinner
        DesviacinestandarSpinnerLabel  matlab.ui.control.Label
        FiltroGaussianoButton          matlab.ui.control.Button
        FiltrosharpenButton            matlab.ui.control.Button
        HistogramaButton               matlab.ui.control.Button
        VolverButton                   matlab.ui.control.Button
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        AyudaButton                    matlab.ui.control.Button
        RestablecerimagenButton        matlab.ui.control.Button
        QuitarruidoLabel               matlab.ui.control.Label
        FiltropromedioButton           matlab.ui.control.Button
        CambiarimagenButton            matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Imagen)
            app.UIAxes.reset
            axis(app.UIAxes, 'tight')
            imshow(Imagen, 'Parent', app.UIAxes);            
        end

        % Button pushed function: CambiarimagenButton
        function CambiarimagenButtonPushed(app, event)
            % Cargar archivo
                global Imagen 
                startingFolder = 'C:\Users\Hipolito\Downloads\';
                if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
                end
                defaultFileName = fullfile(startingFolder, '*.jpg*');
                [baseFileName, folder] = uigetfile(defaultFileName, 'Seleccionar un archivo');
                if baseFileName == 0
                return;
                end
                fullFileName = fullfile(folder, baseFileName);
                Imagen = imread(fullFileName);
                % Mostrar imagen
                app.UIAxes.reset
                axis(app.UIAxes, 'tight')
                imshow(Imagen, 'Parent', app.UIAxes)
        end

        % Button pushed function: FiltropromedioButton
        function FiltropromedioButtonPushed(app, event)
            global Imagen
            global Imagen2
            Imagen = imresize(Imagen, 2);
            %img_gris = rgb2gray(img);

%% filtro de promedio 
kernel_9 = (1/9).*[1 1 1; %definiendo el kernel 3x3 de promedio de 9 pixeles
                   1 1 1;
                   1 1 1];

%kernel_16 = (1/16).*ones(4); % Kernel de 4x4 de promedio de 16 pixeles
%kernel_25 = (1/25).*ones(5); %Kernel de 5x5 de promedio de 25 pixeles            
Imagen2 = imfilter(Imagen, kernel_9); % filtrado porConvolución

            app.UIAxes.reset
            axis(app.UIAxes, 'tight')
            imshow(Imagen2, 'Parent', app.UIAxes)
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
f = msgbox({'1. Promedia los pixeles vecinos.';'2. Destaca y hace más visibles las variaciones y bordes de la imagen.';'3. Mejoran la capacidad de suavizado.'; ...
    '4. Utiliza la herramienta para reestablecer tu imagen si no te agrada el resultado.';'5. Observa el histograma y compara los resultados.';'6. Puedes guardar tu imagen mejorada con ayuda de las opciones que se muestran arriba de la misma.'}, ...
    'Guia de IMPROVE APP');
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            global Imagen
            global Imagen2
            Imagen=Imagen2; 
            
            close(app.UIFigure);
            MAIN2(Imagen)


        end

        % Button pushed function: RestablecerimagenButton
        function RestablecerimagenButtonPushed(app, event)
            global Imagen
            global Imagen2
            
            app.UIAxes.reset
            axis(app.UIAxes, 'tight')
            imshow(Imagen, 'Parent', app.UIAxes)
            Imagen2=Imagen;
        end

        % Button pushed function: HistogramaButton
        function HistogramaButtonPushed(app, event)
            global Imagen
            global Imagen2
            
            Imagen3=Imagen;
            Imagen3=Imagen2;
            
%             axis(app.UIAxes, 'tight')
            
            Imagen3=rgb2gray(Imagen3)
            figure("Name",'Histograma',"Color",'b')            
            imhist(Imagen3)


        end

        % Button pushed function: FiltrosharpenButton
        function FiltrosharpenButtonPushed(app, event)
            global Imagen
            global Imagen2
            Imagen = imresize(Imagen, 2);
img_gris = rgb2gray(Imagen);

%% filtro de perfilado o agudizado
sharpen_1 = [1 -2 1;   %filtro sharpen tipo 1
            -2  5 -2;
             1 -2 1]; 
sharpen_2 = [-1 -1 -1;   %filtro sharpen tipo 2
             -1  9 -1;
             -1 -1 -1]; 
sharpen_3 = [0 -1 0;   %filtro sharpen tipo 2
            -1  5 -1;
             0 -1 0];   
repujado = [-2 -1 0;   %filtro repujado
            -1  1 1;
             0 1 2];            
         
Imagen2 = imfilter(Imagen, sharpen_3); % filtrado por Convolución

        app.UIAxes.reset
        axis(app.UIAxes, 'tight')
        imshow(Imagen2, 'Parent', app.UIAxes) 
        end

        % Button pushed function: FiltroGaussianoButton
        function FiltroGaussianoButtonPushed(app, event)
            global Imagen
            global Imagen2
            Imagen = imresize(Imagen, 2);
            %Imagen3 = rgb2gray(Imagen);

%% filtro Gaussiano
desv = app.Spinner.Value;
gauss_filt = fspecial('gaussian', 20, desv); 
Imagen2 = imfilter(Imagen, gauss_filt);
        
        app.UIAxes.reset
        axis(app.UIAxes, 'tight')
        imshow(Imagen2, 'Parent', app.UIAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.CambiarimagenButton = uibutton(app.UIFigure, 'push');
            app.CambiarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CambiarimagenButtonPushed, true);
            app.CambiarimagenButton.Text = 'Cambiar imagen';
            app.FiltropromedioButton = uibutton(app.UIFigure, 'push');
            app.FiltropromedioButton.ButtonPushedFcn = createCallbackFcn(app, @FiltropromedioButtonPushed, true);
            app.FiltropromedioButton.Text = 'Filtro promedio';
            app.QuitarruidoLabel = uilabel(app.UIFigure);
            app.QuitarruidoLabel.Text = 'Quitar ruido';
            app.RestablecerimagenButton = uibutton(app.UIFigure, 'push');
            app.RestablecerimagenButton.ButtonPushedFcn = createCallbackFcn(app, @RestablecerimagenButtonPushed, true);
            app.RestablecerimagenButton.Text = 'Restablecer imagen';
            app.AyudaButton = uibutton(app.UIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda (?)';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'UNITEC IMG.jpg';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = '<-Volver';
            app.HistogramaButton = uibutton(app.UIFigure, 'push');
            app.HistogramaButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramaButtonPushed, true);
            app.HistogramaButton.Text = 'Histograma';
            app.FiltrosharpenButton = uibutton(app.UIFigure, 'push');
            app.FiltrosharpenButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrosharpenButtonPushed, true);
            app.FiltrosharpenButton.Text = 'Filtro sharpen';
            app.FiltroGaussianoButton = uibutton(app.UIFigure, 'push');
            app.FiltroGaussianoButton.ButtonPushedFcn = createCallbackFcn(app, @FiltroGaussianoButtonPushed, true);
            app.FiltroGaussianoButton.Text = 'Filtro Gaussiano';
            app.DesviacinestandarSpinnerLabel = uilabel(app.UIFigure);
            app.DesviacinestandarSpinnerLabel.Text = 'Desviación estandar:';
            app.Spinner = uispinner(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ruido4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end